classdef ImageLabelingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PreviousButton    matlab.ui.control.Button
        NextButton        matlab.ui.control.Button
        BackgroundButton  matlab.ui.control.Button
        ForegroundButton  matlab.ui.control.Button
        BoundaryButton    matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data (1, :) cell
        num_data (1,1) double = 0
        current_idx (1,1) double = 0
        data_label (1, :) int16
        
        background_label (1,1) int16 = 0;
        foreground_label (1,1) int16 = 1;
        boundary_label (1,1) int16 = 2;
    end
        
    methods (Access = private)
        
        function update_figure(app)
            imagesc(app.UIAxes, app.data{app.current_idx});
            im_size = size(app.data{app.current_idx});
            app.UIAxes.Title.String = sprintf('Image %d', app.current_idx);
            app.UIAxes.XLim = [0, im_size(2)];
            app.UIAxes.YLim = [0, im_size(1)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_cell)
            app.data = data_cell;
            app.num_data = numel(app.data);
            app.data_label = -ones(1, app.num_data);
            if app.num_data > 0
                app.current_idx = 1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.current_idx < app.num_data
                app.current_idx = app.current_idx + 1;
                app.update_figure();
            else
                fprintf('Reach the end of the dataset\n');
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.current_idx > 1
                app.current_idx = app.current_idx - 1;
                app.update_figure();
            else
                fprintf('Reach the begining of the dataset\n');
            end                
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            app.data_label(app.current_idx) = app.background_label;
            app.NextButtonPushed();
        end

        % Button pushed function: ForegroundButton
        function ForegroundButtonPushed(app, event)
            app.data_label(app.current_idx) = app.foreground_label;
            app.NextButtonPushed();
        end

        % Button pushed function: BoundaryButton
        function BoundaryButtonPushed(app, event)
            app.data_label(app.current_idx) = app.boundary_label;
            app.NextButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackgroundButton = uibutton(app.UIFigure, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.ForegroundButton = uibutton(app.UIFigure, 'push');
            app.ForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @ForegroundButtonPushed, true);
            app.ForegroundButton.Text = 'Foreground';
            app.BoundaryButton = uibutton(app.UIFigure, 'push');
            app.BoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryButtonPushed, true);
            app.BoundaryButton.Text = 'Boundary';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageLabelingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end